classdef MeterWeightSetting_V3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Label_14          matlab.ui.control.Label
        Label_13          matlab.ui.control.Label
        Label_12          matlab.ui.control.Label
        Label_11          matlab.ui.control.Label
        Label_10          matlab.ui.control.Label
        Label_9           matlab.ui.control.Label
        Label_8           matlab.ui.control.Label
        Label_7           matlab.ui.control.Label
        Slider            matlab.ui.control.Slider
        Label_6           matlab.ui.control.Label
        Button_8          matlab.ui.control.Button
        Button_7          matlab.ui.control.Button
        BlockButtonGroup  matlab.ui.container.ButtonGroup
        Button_6          matlab.ui.control.ToggleButton
        Button_5          matlab.ui.control.ToggleButton
        Button_4          matlab.ui.control.ToggleButton
        Button_3          matlab.ui.control.ToggleButton
        Label_5           matlab.ui.control.Label
        Button_2          matlab.ui.control.Button
        Button_1          matlab.ui.control.Button
        TextArea          matlab.ui.control.TextArea
        Label             matlab.ui.control.Label
        UIAxes_2          matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
    end

    properties (Access = private)
        OriginalImage = []
        ValidImage = []
        SourceFilePath = ""
        BlockSize = 16
        OriginalImageSize = []
        ValidImageSize = []
        BlockRows = 0
        BlockCols = 0
        WeightMatrix = []
        UndoWeightMatrix = []
        BlockCenterX = []
        BlockCenterY = []
        CurrentWeight = NaN
        OriginalImageHandle = []
        ValidImageHandle = []
        WeightOverlayHandle = []
        GridLineHandle = []
        ActiveROI = []
        IsBusy = false
        IsDirty = false
    end

    methods (Access = private)
        function model = initializeBlockModel(app, imageData, filePath, blockSize) %#ok<INUSD>
            if isempty(imageData) || ndims(imageData) > 3 || ...
                    ~ismember(size(imageData, 3), [1 3])
                error('MeterWeightSetting:ImageType', '请选择灰度或 RGB 图像。');
            end
            validH = floor(size(imageData, 1) / blockSize) * blockSize;
            validW = floor(size(imageData, 2) / blockSize) * blockSize;
            if validH == 0 || validW == 0
                error('MeterWeightSetting:ImageTooSmall', '图像小于所选 block，无法形成完整 block。');
            end
            model.OriginalImage = imageData;
            model.ValidImage = imageData(1:validH, 1:validW, :);
            model.SourceFilePath = filePath;
            model.BlockSize = blockSize;
            model.OriginalImageSize = size(imageData);
            model.ValidImageSize = size(model.ValidImage);
            model.BlockRows = validH / blockSize;
            model.BlockCols = validW / blockSize;
            model.WeightMatrix = ones(model.BlockRows, model.BlockCols);
            [model.BlockCenterX, model.BlockCenterY] = meshgrid( ...
                ((1:model.BlockCols) - 0.5) * blockSize + 0.5, ...
                ((1:model.BlockRows) - 0.5) * blockSize + 0.5);
            model.IsDirty = false;
        end

        function renderImages(app)
            % Create replacement graphics before removing the old preview.
            oldHandles = [app.OriginalImageHandle, app.ValidImageHandle, ...
                app.WeightOverlayHandle, app.GridLineHandle];
            newHandles = gobjects(0);
            axesProperties = {'XLim','YLim','YDir','DataAspectRatio','XTick','YTick','CLim','Colormap'};
            oldOriginalAxes = get(app.UIAxes, axesProperties);
            oldValidAxes = get(app.UIAxes_2, axesProperties);
            oldTitle = app.UIAxes_2.Title.String;
            try
                hOriginal = image(app.UIAxes, 'CData', app.OriginalImage, ...
                    'CDataMapping', 'scaled', 'Visible', 'off');
                newHandles(end+1) = hOriginal;
                hValid = image(app.UIAxes_2, 'CData', app.ValidImage, ...
                    'CDataMapping', 'scaled', 'Visible', 'off', 'HitTest', 'off');
                newHandles(end+1) = hValid;
                [colors, alpha] = app.weightOverlayData();
                x = [app.BlockCenterX(1,1), app.BlockCenterX(1,end)];
                y = [app.BlockCenterY(1,1), app.BlockCenterY(end,1)];
                % MATLAB gives a singleton image cell three times its X/YData span.
                % Choose that span so its outer edges still match the one block.
                if app.BlockCols == 1, x = 0.5 + app.BlockSize * [1/3 2/3]; end
                if app.BlockRows == 1, y = 0.5 + app.BlockSize * [1/3 2/3]; end
                hOverlay = image(app.UIAxes_2, 'CData', colors, ...
                    'XData', x, 'YData', y, 'AlphaData', alpha, ...
                    'AlphaDataMapping', 'none', 'Visible', 'off', ...
                    'HitTest', 'off', 'PickableParts', 'none');
                newHandles(end+1) = hOverlay;
                [gx, gy] = app.createGridLineData();
                hGrid = line(app.UIAxes_2, gx, gy, 'Color', [0.55 0.55 0.55], ...
                    'LineWidth', 0.5, 'Visible', 'off', ...
                    'HitTest', 'off', 'PickableParts', 'none');
                newHandles(end+1) = hGrid;
                app.configureImageAxes(app.UIAxes, app.OriginalImage);
                app.configureImageAxes(app.UIAxes_2, app.ValidImage);
                app.updateRightAxesTitle();
                set(newHandles, 'Visible', 'on');
            catch exception
                delete(newHandles(isgraphics(newHandles)));
                if isvalid(app) && isgraphics(app.UIFigure)
                    set(app.UIAxes, axesProperties, oldOriginalAxes);
                    set(app.UIAxes_2, axesProperties, oldValidAxes);
                    app.UIAxes_2.Title.String = oldTitle;
                end
                rethrow(exception);
            end
            delete(oldHandles(isgraphics(oldHandles)));
            app.OriginalImageHandle = hOriginal;
            app.ValidImageHandle = hValid;
            app.WeightOverlayHandle = hOverlay;
            app.GridLineHandle = hGrid;

        end

        function configureImageAxes(app, ax, img) %#ok<INUSD>
            ax.XLim = [0.5 size(img,2)+0.5];
            ax.YLim = [0.5 size(img,1)+0.5];
            ax.YDir = 'reverse';
            ax.DataAspectRatio = [1 1 1];
            ax.XTick = [];
            ax.YTick = [];
            colormap(ax, gray(256));
            if isinteger(img)
                ax.CLim = [double(intmin(class(img))), double(intmax(class(img)))];
            else
                ax.CLim = [0 1];
            end
            disableDefaultInteractivity(ax);
        end

        function [colors, alpha] = weightOverlayData(app)
            palette = [42 131 95; 0 0 0; 18 84 79] / 255;
            opacity = [0.25; 0; 0.25];
            index = app.WeightMatrix(:) + 1;
            colors = reshape(palette(index,:), app.BlockRows, app.BlockCols, 3);
            alpha = reshape(opacity(index), app.BlockRows, app.BlockCols);
        end

        function refreshWeightOverlay(app)
            [colors, alpha] = app.weightOverlayData();
            set(app.WeightOverlayHandle, 'CData', colors, 'AlphaData', alpha);
        end

        function [x, y] = createGridLineData(app)
            width = app.ValidImageSize(2);
            height = app.ValidImageSize(1);
            xb = 0.5 + (0:app.BlockCols) * app.BlockSize;
            yb = 0.5 + (0:app.BlockRows) * app.BlockSize;
            vx = [xb; xb; nan(size(xb))];
            vy = [0.5*ones(size(xb)); (height+0.5)*ones(size(xb)); nan(size(xb))];
            hx = [0.5*ones(size(yb)); (width+0.5)*ones(size(yb)); nan(size(yb))];
            hy = [yb; yb; nan(size(yb))];
            x = [vx(:); hx(:)];
            y = [vy(:); hy(:)];
        end

        function handleWeightSelection(app, sourceSwitch, weight, roiColor)
            if app.IsBusy || ~strcmp(sourceSwitch.Value, 'On')
                return;
            end
            app.turnAllWeightSwitchesOff();
            if isempty(app.WeightMatrix)
                app.showError('请先导入图像。');
                return;
            end
            sourceSwitch.Value = 'On';
            app.CurrentWeight = weight;
            app.IsBusy = true;
            cleanup = onCleanup(@()app.finishOperation()); %#ok<NASGU>
            app.updateControlState();
            try
                roi = drawfreehand(app.UIAxes_2, 'Closed', true, ...
                    'Color', roiColor, 'FaceAlpha', 0.1, ...
                    'DrawingArea', [0.5 0.5 app.ValidImageSize(2) app.ValidImageSize(1)]);
                if ~isvalid(app) || ~isgraphics(app.UIFigure)
                    if isvalid(roi), delete(roi); end
                    return;
                end
                app.ActiveROI = roi;
                if ~isvalid(roi) || size(roi.Position,1) < 3 || ...
                        any(~isfinite(roi.Position(:))) || polyarea(roi.Position(:,1),roi.Position(:,2)) == 0
                    return;
                end
                selected = inROI(roi, app.BlockCenterX, app.BlockCenterY);
                if ~any(selected(:))
                    uialert(app.UIFigure, '本次圈选未覆盖完整 block 中心', ...
                        '圈选提示', 'Icon', 'info');
                    return;
                end
                if any(app.WeightMatrix(selected) ~= weight)
                    before = app.WeightMatrix;
                    after = before;
                    after(selected) = weight;
                    app.commitWeights(after, before, true);
                end
            catch exception
                app.showError(exception.message);
            end
        end

        function setWeightControlsEnabled(app, enabled)
            set([app.Switch_1 app.Switch_3], 'Enable', enabled);
        end

        function turnAllWeightSwitchesOff(app)
            set([app.Switch_1 app.Switch_3], 'Value', 'Off');
            app.CurrentWeight = NaN;
        end

        function updateControlState(app)
            if ~isvalid(app) || ~isgraphics(app.UIFigure), return; end
            idle = ~app.IsBusy;
            hasImage = ~isempty(app.WeightMatrix);
            hasUndo = ~isempty(app.UndoWeightMatrix);
            hasWeights = hasImage && any(app.WeightMatrix(:) ~= 1);
            app.Button_1.Enable = matlab.lang.OnOffSwitchState(idle);
            set([app.Button_3 app.Button_4 app.Button_5 app.Button_6], ...
                'Enable', matlab.lang.OnOffSwitchState(idle));
            enabled = matlab.lang.OnOffSwitchState(idle && hasImage);
            app.Button_2.Enable = enabled;
            app.setWeightControlsEnabled(enabled);
            app.Button_7.Enable = matlab.lang.OnOffSwitchState(idle && hasImage && hasUndo);
            app.Button_8.Enable = matlab.lang.OnOffSwitchState(idle && hasImage && (hasWeights || hasUndo));
            app.syncBlockSizeButtons();
        end

        function finishOperation(app)
            if ~isvalid(app) || ~isgraphics(app.UIFigure), return; end
            app.cleanupActiveROI();
            app.turnAllWeightSwitchesOff();
            app.IsBusy = false;
            app.updateControlState();
        end

        function cleanupActiveROI(app)
            if ~isempty(app.ActiveROI) && isvalid(app.ActiveROI)
                delete(app.ActiveROI);
            end
            app.ActiveROI = [];
            % The handle from drawfreehand is returned only after drawing.
            % Find an unfinished ROI as well when the window is closed.
            if isgraphics(app.UIAxes_2)
                children = findall(app.UIAxes_2);
                for k = 1:numel(children)
                    if isa(children(k), 'images.roi.Freehand') && isvalid(children(k))
                        delete(children(k));
                    end
                end
            end
        end

        function resetAllWeights(app)
            if app.IsBusy || isempty(app.WeightMatrix), return; end
            changed = any(app.WeightMatrix(:) ~= 1);
            if ~changed && isempty(app.UndoWeightMatrix), return; end
            app.IsBusy = true;
            cleanup = onCleanup(@()app.finishOperation()); %#ok<NASGU>
            app.updateControlState();
            try
                choice = uiconfirm(app.UIFigure, ...
                    '全部权重恢复为1，并清空撤销记录；该重置不可撤销。是否继续？', ...
                    '重置所有框选', 'Options', {'重置', '取消'}, ...
                    'DefaultOption', 2, 'CancelOption', 2);
                if ~isvalid(app) || ~isgraphics(app.UIFigure), return; end
                if strcmp(choice, '重置')
                    app.commitWeights(ones(size(app.WeightMatrix)), [], app.IsDirty || changed);
                end
            catch exception
                app.showError(exception.message);
            end
        end

        function updateRightAxesTitle(app)
            title(app.UIAxes_2, {sprintf('Block权重矩阵 | B=%d | %d×%d', ...
                app.BlockSize, app.BlockRows, app.BlockCols), ...
                '墨绿=2，绿=0，不叠色=1（默认）'}, 'Interpreter', 'none');
        end

        function syncBlockSizeButtons(app)
            buttons = [app.Button_3 app.Button_4 app.Button_5 app.Button_6];
            sizes = [8 16 32 64];
            selected = buttons(sizes == app.BlockSize);
            app.BlockButtonGroup.SelectedObject = selected;
            set(buttons, 'BackgroundColor', [0.96 0.96 0.96]);
            selected.BackgroundColor = [0.5333 0.7412 0.6431];
        end

        function applyBlockModel(app, model)
            fields = fieldnames(model);
            previous = struct();
            previousUndo = app.UndoWeightMatrix;
            for k = 1:numel(fields)
                previous.(fields{k}) = app.(fields{k});
            end
            try
                for k = 1:numel(fields), app.(fields{k}) = model.(fields{k}); end
                app.renderImages();
                app.clearUndoRecord();
            catch exception
                if isvalid(app)
                    for k = 1:numel(fields), app.(fields{k}) = previous.(fields{k}); end
                    app.UndoWeightMatrix = previousUndo;
                end
                rethrow(exception);
            end
        end

        function commitWeights(app, after, undoMatrix, dirty)
            before = app.WeightMatrix;
            previousUndo = app.UndoWeightMatrix;
            previousDirty = app.IsDirty;
            try
                app.WeightMatrix = after;
                app.refreshWeightOverlay();
                app.UndoWeightMatrix = undoMatrix;
                app.IsDirty = dirty;
            catch exception
                if isvalid(app)
                    app.WeightMatrix = before;
                    app.UndoWeightMatrix = previousUndo;
                    app.IsDirty = previousDirty;
                    if isgraphics(app.UIFigure)
                        try
                            app.refreshWeightOverlay();
                        catch restoreException
                            exception = addCause(exception, restoreException);
                        end
                    end
                end
                rethrow(exception);
            end
        end

        function clearUndoRecord(app)
            app.UndoWeightMatrix = [];
        end

        function undoLastSelection(app)
            if app.IsBusy || isempty(app.WeightMatrix) || isempty(app.UndoWeightMatrix), return; end
            if ~isequal(size(app.UndoWeightMatrix), size(app.WeightMatrix))
                app.clearUndoRecord();
                app.updateControlState();
                app.showError('撤销记录与当前权重矩阵尺寸不一致，已清理无效记录。');
                return;
            end
            app.IsBusy = true;
            cleanup = onCleanup(@()app.finishOperation()); %#ok<NASGU>
            app.updateControlState();
            try
                app.commitWeights(app.UndoWeightMatrix, [], true);
            catch exception
                app.showError(exception.message);
            end
        end

        function changeBlockSize(app, selectedButton)
            if app.IsBusy
                app.syncBlockSizeButtons();
                return;
            end
            buttons = [app.Button_3 app.Button_4 app.Button_5 app.Button_6];
            sizes = [8 16 32 64];
            newSize = sizes(buttons == selectedButton);
            if isempty(newSize) || newSize == app.BlockSize
                app.syncBlockSizeButtons();
                return;
            end
            if isempty(app.OriginalImage)
                app.BlockSize = newSize;
                app.syncBlockSizeButtons();
                return;
            end
            app.IsBusy = true;
            cleanup = onCleanup(@()app.finishOperation()); %#ok<NASGU>
            app.updateControlState();
            try
                model = app.initializeBlockModel(app.OriginalImage, app.SourceFilePath, newSize);
                if any(app.WeightMatrix(:) ~= 1) || ~isempty(app.UndoWeightMatrix)
                    choice = uiconfirm(app.UIFigure, ...
                        '更改 block 尺寸将重置所有权重并清空撤销记录，是否继续？', ...
                        '更改 block 尺寸', 'Options', {'继续', '取消'}, ...
                        'DefaultOption', 2, 'CancelOption', 2);
                    if ~isvalid(app) || ~isgraphics(app.UIFigure) || ~strcmp(choice, '继续')
                        return;
                    end
                end
                model.IsDirty = true;
                app.applyBlockModel(model);
            catch exception
                app.showError(exception.message);
            end
        end

        function showError(app, message)
            if isvalid(app) && isgraphics(app.UIFigure)
                uialert(app.UIFigure, message, '操作提示');
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.BlockSize = 16;
            app.BlockButtonGroup.SelectedObject = app.Button_4;
            app.Button_7.Tooltip = '仅撤销最近一次有效赋权';
            app.clearUndoRecord();
            app.turnAllWeightSwitchesOff();
            app.updateControlState();
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: Button_1
        function ImportImageButtonPushed(app, event)
            if app.IsBusy, return; end
            app.IsBusy = true;
            cleanup = onCleanup(@()app.finishOperation()); %#ok<NASGU>
            app.updateControlState();
            try
                if app.IsDirty
                    choice = uiconfirm(app.UIFigure, '放弃未导出的权重修改并重新导入？', ...
                        '重新导入', 'Options', {'放弃并导入', '取消'}, ...
                        'DefaultOption', 2, 'CancelOption', 2);
                    if ~isvalid(app) || ~isgraphics(app.UIFigure) || ~strcmp(choice, '放弃并导入')
                        return;
                    end
                end
                [name, folder] = uigetfile( ...
                    {'*.png;*.jpg;*.jpeg;*.bmp;*.tif;*.tiff', '图像文件 (PNG/JPEG/BMP/TIFF)'}, '导入图像');
                if isequal(name, 0), return; end
                filePath = fullfile(folder, name);
                [img, map] = imread(filePath);
                if ~isempty(map), img = ind2rgb(img, map); end
                if ~isvalid(app) || ~isgraphics(app.UIFigure), return; end
                model = app.initializeBlockModel(img, filePath, app.BlockSize);
                app.applyBlockModel(model);
            catch exception
                app.showError(exception.message);
            end
        end

        % Button pushed function: Button_2
        function ExportWeightMatrixButtonPushed(app, event)
            if app.IsBusy, return; end
            if isempty(app.WeightMatrix)
                app.showError('请先导入图像。');
                return;
            end
            app.IsBusy = true;
            cleanup = onCleanup(@()app.finishOperation()); %#ok<NASGU>
            app.updateControlState();
            try
                [folder, name, ext] = fileparts(app.SourceFilePath);
                defaultName = fullfile(folder, sprintf('%s_weight_B%d.mat', name, app.BlockSize));
                [fileName, pathName] = uiputfile({'*.mat', 'MAT 文件'}, '导出权重矩阵', defaultName);
                if isequal(fileName, 0) || ~isvalid(app) || ~isgraphics(app.UIFigure), return; end
                WeightMatrix = app.WeightMatrix;
                blockSize = app.BlockSize;
                originalImageSize = app.OriginalImageSize;
                validImageSize = app.ValidImageSize;
                sourceImageName = [name ext];
                save(fullfile(pathName, fileName), 'WeightMatrix', 'blockSize', ...
                    'originalImageSize', 'validImageSize', 'sourceImageName');
                app.IsDirty = false;
                if all(WeightMatrix(:) == 0)
                    uialert(app.UIFigure, '全0权重不能直接用于归一化加权测光。', ...
                        '导出提示', 'Icon', 'warning');
                end
            catch exception
                app.showError(exception.message);
            end
        end

        % Callback function
        function Weight1SwitchValueChanged(app, event)
            app.handleWeightSelection(app.Switch_1, 2, [18 84 79]/255);
        end

        % Callback function
        function Weight3SwitchValueChanged(app, event)
            app.handleWeightSelection(app.Switch_3, 0, [42 131 95]/255);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.cleanupActiveROI();
            delete(app);
        end

        % Selection changed function: BlockButtonGroup
        function BlockButtonGroupSelectionChanged(app, event)
            app.changeBlockSize(event.NewValue);
        end

        % Button pushed function: Button_7
        function UndoLastSelectionButtonPushed(app, event)
            app.undoLastSelection();
        end

        % Button pushed function: Button_8
        function ResetAllSelectionsButtonPushed(app, event)
            app.resetAllWeights();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '原图')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Block权重矩阵')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'自动测光Block权重赋予界面'};
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButtonPushed, true);
            app.Button_1.Text = '导入图像';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @ExportWeightMatrixButtonPushed, true);
            app.Button_2.Text = '导出权重矩阵';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = {'不圈选'; '权重默认为1'};
            app.BlockButtonGroup = uibuttongroup(app.UIFigure);
            app.BlockButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BlockButtonGroupSelectionChanged, true);
            app.BlockButtonGroup.Title = 'Block尺寸设置';
            app.Button_3 = uitogglebutton(app.BlockButtonGroup);
            app.Button_3.Text = '[8, 8]';
            app.Button_4 = uitogglebutton(app.BlockButtonGroup);
            app.Button_4.Text = '[16, 16]';
            app.Button_4.Value = true;
            app.Button_5 = uitogglebutton(app.BlockButtonGroup);
            app.Button_5.Text = '[32, 32]';
            app.Button_6 = uitogglebutton(app.BlockButtonGroup);
            app.Button_6.Text = '[64, 64]';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @UndoLastSelectionButtonPushed, true);
            app.Button_7.Text = '撤销上个圈选';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @ResetAllSelectionsButtonPushed, true);
            app.Button_8.Text = '重置所有圈选';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '权重设置';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 8];
            app.Slider.MinorTicks = [];
            app.Slider.Value = 1;
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '红';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '粉';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '橙';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '黄';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '绿';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '青';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '蓝';
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '紫';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MeterWeightSetting_V3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end